classdef funcUploadButtonPushedChecksData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RightPanel                    matlab.ui.container.Panel
        TabGroup4_2                   matlab.ui.container.TabGroup
        S1Tab_5                       matlab.ui.container.Tab
        UIAxesAnimationS1             matlab.ui.control.UIAxes
        S2Tab_5                       matlab.ui.container.Tab
        UIAxesAnimationS2             matlab.ui.control.UIAxes
        StewartPlatformMotionPreviewLabel  matlab.ui.control.Label
        TabGroup4                     matlab.ui.container.TabGroup
        S1Tab_4                       matlab.ui.container.Tab
        UIAxesWaveS1                  matlab.ui.control.UIAxes
        S2Tab_4                       matlab.ui.container.Tab
        UIAxesWaveS2                  matlab.ui.control.UIAxes
        MotionWavePreviewLabel        matlab.ui.control.Label
        LeftPanel                     matlab.ui.container.Panel
        SendButton                    matlab.ui.control.Button
        SendDatatoArduinoLabel        matlab.ui.control.Label
        S2Button                      matlab.ui.control.Button
        S1Button                      matlab.ui.control.Button
        StartStewartPlatformMotionPreviewLabel  matlab.ui.control.Label
        TabGroup4_3                   matlab.ui.container.TabGroup
        S1Tab_6                       matlab.ui.container.Tab
        Panel_4                       matlab.ui.container.Panel
        UsePredefinedMotionLabel      matlab.ui.control.Label
        SetParametersButtonS1         matlab.ui.control.Button
        DimensionDropDownS1           matlab.ui.control.DropDown
        DimensionDropDownLabel        matlab.ui.control.Label
        ChooseFunctionDropDownS1      matlab.ui.control.DropDown
        FunctionLabel                 matlab.ui.control.Label
        Panel_3                       matlab.ui.container.Panel
        UseCustomMotionLabel          matlab.ui.control.Label
        FileLocationEditField         matlab.ui.control.EditField
        FileLocationEditFieldLabel    matlab.ui.control.Label
        UploadButton                  matlab.ui.control.Button
        S2Tab_6                       matlab.ui.container.Tab
        Panel_6                       matlab.ui.container.Panel
        UsePredefinedMotionLabel_2    matlab.ui.control.Label
        SetParametersButtonS2         matlab.ui.control.Button
        DimensionDropDownS2           matlab.ui.control.DropDown
        DimensionDropDown_2Label      matlab.ui.control.Label
        ChooseFunctionDropDownS2      matlab.ui.control.DropDown
        FunctionLabel_2               matlab.ui.control.Label
        Panel_5                       matlab.ui.container.Panel
        UseCustomMotionLabel_2        matlab.ui.control.Label
        FileLocationEditField_2       matlab.ui.control.EditField
        FileLocationEditField_2Label  matlab.ui.control.Label
        UploadButton_2                matlab.ui.control.Button
        VerifyButton                  matlab.ui.control.Button
        VerifyMotionLabel             matlab.ui.control.Label
        MotionFileLabel               matlab.ui.control.Label
        TabGroup_2                    matlab.ui.container.TabGroup
        S2toS1Tab                     matlab.ui.container.Tab
        SaveValuesButton_3            matlab.ui.control.Button
        rollEditField                 matlab.ui.control.NumericEditField
        rollEditFieldLabel            matlab.ui.control.Label
        zEditField                    matlab.ui.control.NumericEditField
        zEditFieldLabel               matlab.ui.control.Label
        pitchEditField                matlab.ui.control.NumericEditField
        pitchEditFieldLabel           matlab.ui.control.Label
        yawEditField_2                matlab.ui.control.NumericEditField
        yawEditField_2Label           matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        InitialRelativePositionofTargetsrelativetoS1Label  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        S1Tab                         matlab.ui.container.Tab
        SaveValuesButton              matlab.ui.control.Button
        P2yEditField                  matlab.ui.control.NumericEditField
        P2yEditFieldLabel             matlab.ui.control.Label
        P2xEditField                  matlab.ui.control.NumericEditField
        P2xEditFieldLabel             matlab.ui.control.Label
        P1yEditField                  matlab.ui.control.NumericEditField
        P1yEditFieldLabel             matlab.ui.control.Label
        P1xEditField                  matlab.ui.control.NumericEditField
        P1xEditFieldLabel             matlab.ui.control.Label
        S2Tab                         matlab.ui.container.Tab
        SaveValuesButton_2            matlab.ui.control.Button
        P2yEditField_4                matlab.ui.control.NumericEditField
        P2yEditField_4Label           matlab.ui.control.Label
        P2xEditField_4                matlab.ui.control.NumericEditField
        P2xEditField_4Label           matlab.ui.control.Label
        P1yEditField_4                matlab.ui.control.NumericEditField
        P1yEditField_4Label           matlab.ui.control.Label
        P1xEditField_4                matlab.ui.control.NumericEditField
        P1xEditField_4Label           matlab.ui.control.Label
        CoordinatesofStewartPlatformsS1andS2Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogApp 
        DataMapS1 containers.Map
        DataMapS2 containers.Map
        % configurations for Stewart Platform
        bArray
        pArrayPlatform
        s
        a
        betaArray
        baseToTop % from center of base coordinate to top platform coordinate (at home position)
        topToTarget % 3 * 1, from centor of the top platform to center of the target (at home position) in home top platform coordinate

        % Stewart Platform motion related calculated data
        pArrayAllTime
        servoAngleAllTime
        housingUnitVectorsAllTime
        tS1
        transformed_tS1

    end
    
    methods (Access = private)
        
        function initializeDataMap(app)
            app.DataMapS1 = containers.Map;
            app.DataMapS2 = containers.Map;
            keysListS1 = {'DataS1X', 'DataS1Y', 'DataS1Z', 'DataS1Yaw', 'DataS1Pitch', 'DataS1Roll'};
            keysListS2 = {'DataS2X', 'DataS2Y', 'DataS2Z', 'DataS2Yaw', 'DataS2Pitch', 'DataS2Roll'};
            for i = 1:length(keysListS1)
                app.DataMapS1(keysListS1{i}) = [];
            end
            for i = 1:length(keysListS2)
                app.DataMapS2(keysListS2{i}) = [];
            end
        end
        
        function initializeStewartPlatformConfigurations(app)
            app.bArray = [99, 64, -46, -88, -46, 35; ...
                         0, 71, 81, 14, -81, -88; ...
                         0, 0, 0, 0, 0, 0]; % 3 * 6
            app.pArrayPlatform = [45, 11, -11, -41, -27, 34; ...
                             -11, 43, 43, -11, -29, -29; ...
                             0, 0, 0, 0, 0, 0]; % 3 * 6
            app.s = 100;
            app.a = 25;
            app.betaArray = deg2rad([120 + 180, 120 + 180, 240 + 180, ...
                240 + 180, 360 + 180, 360 + 180]); % 1 * 6, add 180 since motors face inwards
            app.baseToTop = [0; 0; 85];
            app.topToTarget = [152; -95; 51];
            
        end
        
     
    end
    
    methods (Access = public)

        function switchSetParameterButtons(app, swtich)
            app.SetParametersButtonS1.Enable = swtich;
            app.SetParametersButtonS2.Enable = swtich;
        end

        
        function updateMotionData(app, platformNumber, ...
                dimension, motionX, motionY)
            if platformNumber == 1
                dataMap = app.DataMapS1;
                plotAxes = app.UIAxesWaveS1;    
            else
                dataMap = app.DataMapS2;
                plotAxes = app.UIAxesWaveS2;   
            end

            key = char(strcat('DataS', num2str(platformNumber), ...
                        string(upper(dimension(1))), string(dimension(2:end)))); 
            dataMap(key) = {motionX, motionY};
            keys = dataMap.keys;

            cla(plotAxes);
            hold(plotAxes, 'on');
            for i = 1:length(keys)
                curveData = dataMap(keys{i});
                if ~isempty(curveData)
                    plot(plotAxes, curveData{1}, curveData{2}, 'DisplayName', keys{i});
                end
            end
            hold(plotAxes, 'off');
            legend(plotAxes, 'show');
  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 
            % % Get the size of the screen
            % screenSize = get(0, 'ScreenSize'); % Returns [left, bottom, width, height]
            % screenWidth = screenSize(3);
            % screenHeight = screenSize(4);
            % 
            % % Define the desired size of the GUI as a fraction of the screen size
            % guiWidth = screenWidth * 1; % GUI width is 50% of the screen width
            % guiHeight = screenHeight * 1; % GUI height is 50% of the screen height
            % 
            % % Calculate the position to center the GUI on the screen
            % guiLeft = (screenWidth - guiWidth) / 2;
            % guiBottom = (screenHeight - guiHeight) / 2;
            % 
            % % Create the GUI and set its position and size
            % f = app.UIFigure;
            % f.Position = [guiLeft, guiBottom, guiWidth, guiHeight];

            initializeDataMap(app);
            initializeStewartPlatformConfigurations(app);

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
           % Open file dialog
            [fileName, filePath] = uigetfile({'*.txt;*.csv', 'Text files (*.txt, *.csv)'; '*.*', 'All files (*.*)'}, 'Select Data File');
            
            % Check if the user selected a file
            if fileName ~= 0
                try
                    % Read data from the file
                    data = dlmread(fullfile(filePath, fileName));
            
                    % Extract the first row as NumberOfDataPoints
                    NumberOfDataPoints = data(1);
            
                    % Extract the second row as SamplingRate
                    SamplingRate = data(2);
            
                    % Extract columns after the second row as separate variables
                    XData = data(3:end, 1);
                    YData = data(3:end, 2);
                    ZData = data(3:end, 3);
                    YawData = data(3:end, 4);
                    PitchData = data(3:end, 5);
                    RollData = data(3:end, 6);

                    % Check for empty columns
                    isEmptyXData = isempty(XData);
                    isEmptyYData = isempty(YData);
                    isEmptyZData = isempty(ZData);
                    isEmptyYawData = isempty(YawData);
                    isEmptyPitchData = isempty(PitchData);
                    isEmptyRollData = isempty(RollData);
            
                    if isEmptyXData
                        warndlg('Warning: XData is empty.');
                    end
            
                    if isEmptyYData
                        warndlg('Warning: YData is empty.');
                    end
            
                    if isEmptyZData
                        warndlg('Warning: ZData is empty.');
                    end
            
                    if isEmptyYawData
                        warndlg('Warning: YawData is empty.');
                    end
            
                    if isEmptyPitchData
                        warndlg('Warning: PitchData is empty.');
                    end
            
                    if isEmptyRollData
                        warndlg('Warning: RollData is empty.');
                    end

                  
                    % Check for valid values
                    isValidXData = all(~isnan(XData));
                    isValidYData = all(~isnan(YData));
                    isValidZData = all(~isnan(ZData));
                    isValidYawData = all(~isnan(YawData));
                    isValidPitchData = all(~isnan(PitchData));
                    isValidRollData = all(~isnan(RollData));
            
                    % Check the number of data points
                    isValidNumberOfDataPoints = NumberOfDataPoints == length(XData);
                                
                    % Display validity and data types
                    if isValidXData && isValidYData && isValidZData && isValidYawData && isValidPitchData && isValidRollData && isValidNumberOfDataPoints
                        disp('All values are valid.');
                    else
                        disp('Warning: Some values are not valid or inconsistent.');
                    end
            
                    fprintf('Data type of XData: %s\n', class(XData));
                    fprintf('Data type of YData: %s\n', class(YData));
                    fprintf('Data type of ZData: %s\n', class(ZData));
                    fprintf('Data type of YawData: %s\n', class(YawData));
                    fprintf('Data type of PitchData: %s\n', class(PitchData));
                    fprintf('Data type of RollData: %s\n', class(RollData));
            
                    % Display warnings for inconsistency or empty columns
                    if ~isValidNumberOfDataPoints
                        warndlg('Warning: Inconsistent number of data points.');
                    end
                                
            
                catch
                    % Display a warning pop-up message if an error occurs
                    warndlg('Error reading the motion file data. Missing a column of data or data contains non-numerical value.');
                end
 
                % TODO: data should save to private property


            end

        end

        % Button pushed function: UploadButton_2
        function UploadButton_2Pushed(app, event)
            % Open file dialog
            [fileName, filePath] = uigetfile({'*.txt;*.csv', 'Text files (*.txt, *.csv)'; '*.*', 'All files (*.*)'}, 'Select Data File');
            
            % Check if the user selected a file
            if fileName ~= 0
                try
                    % Read data from the file
                    data2 = dlmread(fullfile(filePath, fileName));
            
                    % Extract the first row as NumberOfDataPoints
                    NumberOfDataPoints2 = data2(1);
            
                    % Extract the second row as SamplingRate
                    SamplingRate2 = data2(2);
            
                    % Extract columns after the second row as separate variables
                    XData2 = data2(3:end, 1);
                    YData2 = data2(3:end, 2);
                    ZData2 = data2(3:end, 3);
                    YawData2 = data2(3:end, 4);
                    PitchData2 = data2(3:end, 5);
                    RollData2 = data2(3:end, 6);

                    % Check for empty columns
                    isEmptyXData = isempty(XData2);
                    isEmptyYData = isempty(YData2);
                    isEmptyZData = isempty(ZData2);
                    isEmptyYawData = isempty(YawData2);
                    isEmptyPitchData = isempty(PitchData2);
                    isEmptyRollData = isempty(RollData2);
            
                    if isEmptyXData
                        warndlg('Warning: XData is empty.');
                    end
            
                    if isEmptyYData
                        warndlg('Warning: YData is empty.');
                    end
            
                    if isEmptyZData
                        warndlg('Warning: ZData is empty.');
                    end
            
                    if isEmptyYawData
                        warndlg('Warning: YawData is empty.');
                    end
            
                    if isEmptyPitchData
                        warndlg('Warning: PitchData is empty.');
                    end
            
                    if isEmptyRollData
                        warndlg('Warning: RollData is empty.');
                    end

                  
                    % Check for valid values
                    isValidXData = all(~isnan(XData2));
                    isValidYData = all(~isnan(YData2));
                    isValidZData = all(~isnan(ZData2));
                    isValidYawData = all(~isnan(YawData2));
                    isValidPitchData = all(~isnan(PitchData2));
                    isValidRollData = all(~isnan(RollData2));
            
                    % Check the number of data points
                    isValidNumberOfDataPoints = NumberOfDataPoints2 == length(XData2);
                                
                    % Display validity and data types
                    if isValidXData && isValidYData && isValidZData && isValidYawData && isValidPitchData && isValidRollData && isValidNumberOfDataPoints
                        disp('All values are valid.');
                    else
                        disp('Warning: Some values are not valid or inconsistent.');
                    end
            
                    fprintf('Data type of XData: %s\n', class(XData2));
                    fprintf('Data type of YData: %s\n', class(YData2));
                    fprintf('Data type of ZData: %s\n', class(ZData2));
                    fprintf('Data type of YawData: %s\n', class(YawData2));
                    fprintf('Data type of PitchData: %s\n', class(PitchData2));
                    fprintf('Data type of RollData: %s\n', class(RollData2));
            
                    % Display warnings for inconsistency or empty columns
                    if ~isValidNumberOfDataPoints
                        warndlg('Warning: Inconsistent number of data points.');
                    end
                                
            
                catch
                    % Display a warning pop-up message if an error occurs
                    warndlg('Error reading the motion file data. Missing a column of data or data contains non-numerical value.');
                end

                % TODO: data should save to private property

            end
        end

        % Button pushed function: SaveValuesButton
        function EvaluateS1Position(app, event)
            % Get the entered value from the EditField
            inputValueP1x = app.P1xEditField.Value;
            inputValueP1y = app.P1yEditField.Value;
            inputValueP2x = app.P2xEditField.Value;
            inputValueP2y = app.P2yEditField.Value;

                % Check if the x values are between 0 and 100  
                % Check if the y values are between 0 and 40

                if inputValueP1x < 0 || inputValueP1x > 100
                % Show a warning message
                warndlg('Invalid P1-x input. Please enter a number between 0 and 100.', 'Warning', 'modal');
                end

                if inputValueP2x < 0 || inputValueP2x > 100
                % Show a warning message
                warndlg('Invalid P2-x input. Please enter a number between 0 and 100.', 'Warning', 'modal');
                end

                if inputValueP1y < 0 || inputValueP1y > 40
                % Show a warning message
                warndlg('Invalid P1-y input. Please enter a number between 0 and 40.', 'Warning', 'modal');
                end

                if inputValueP2y < 0 || inputValueP2y > 40
                % Show a warning message
                warndlg('Invalid P2-y input. Please enter a number between 0 and 40.', 'Warning', 'modal');
                end


        end

        % Button pushed function: SaveValuesButton_2
        function EvaluateS2Position(app, event)
            % Get the entered value from the EditField
            inputValueP1x = app.P1xEditField_4.Value;
            inputValueP1y = app.P1yEditField_4.Value;
            inputValueP2x = app.P2xEditField_4.Value;
            inputValueP2y = app.P2yEditField_4.Value;

                % Check if the x values are between 0 and 100  
                % Check if the y values are between 0 and 40

                if inputValueP1x < 0 || inputValueP1x > 100
                % Show a warning message
                warndlg('Invalid P1-x input. Please enter a number between 0 and 100.', 'Warning', 'modal');
                end

                if inputValueP2x < 0 || inputValueP2x > 100
                % Show a warning message
                warndlg('Invalid P2-x input. Please enter a number between 0 and 100.', 'Warning', 'modal');
                end

                if inputValueP1y < 0 || inputValueP1y > 40
                % Show a warning message
                warndlg('Invalid P1-y input. Please enter a number between 0 and 40.', 'Warning', 'modal');
                end

                if inputValueP2y < 0 || inputValueP2y > 40
                % Show a warning message
                warndlg('Invalid P2-y input. Please enter a number between 0 and 40.', 'Warning', 'modal');
                end

        end

        % Button pushed function: SaveValuesButton_3
        function EvaluateRelativeDistance(app, event)
            % Get the entered value from the EditField
            xValue = app.xEditField.Value;
            yValue = app.yEditField.Value;
            zValue = app.zEditField.Value;
            yawValue = app.yawEditField_2.Value;
            pitchValue = app.pitchEditField.Value;
            rollValue = app.rollEditField.Value;
            
                % Max displacement 9.79cm in the x-direction, 27.534cm in the y-direction, and 3.025cm in the z-direction  
                % Check if the relative distance is within twice the max displacement

                if xValue < 0 || xValue > (9.79*2)
                % Show a warning message
                warndlg('Invalid x input. Out of range.', 'Warning', 'modal');
                end
                
                if yValue < 0 || yValue > (27.534*2)
                % Show a warning message
                warndlg('Invalid y input. Out of range.', 'Warning', 'modal');
                end

                if zValue < 0 || zValue > (3.025*2)
                % Show a warning message
                warndlg('Invalid z input. Out of range.', 'Warning', 'modal');
                end

                if yawValue < 0 || yawValue > (9.79*2)
                % Show a warning message
                warndlg('Invalid yaw input. Out of range.', 'Warning', 'modal');
                end
                
                if pitchValue < 0 || pitchValue > (27.534*2)
                % Show a warning message
                warndlg('Invalid pitch input. Out of range.', 'Warning', 'modal');
                end

                if rollValue < 0 || rollValue > (3.025*2)
                % Show a warning message
                warndlg('Invalid roll input. Out of range.', 'Warning', 'modal');
                end

        end

        % Button pushed function: SetParametersButtonS1, 
        % ...and 1 other component
        function openParameterSettingWindow(app, event)
            % disables buttons to prevent open multiple dialog window
            switchSetParameterButtons(app, "off");

            sourceButton = event.Source;
            buttonTag = sourceButton.Tag;
            % displayVariableValue(app, buttonTag);
      
            switch buttonTag
                case 'SetParametersButtonS1'
                    platformNumber = 1;
                    functionTag = app.ChooseFunctionDropDownS1.Value;
                    dimension = app.DimensionDropDownS1.Value;
                case 'SetParametersButtonS2'
                    platformNumber = 2;
                    functionTag = app.ChooseFunctionDropDownS2.Value;
                    dimension = app.DimensionDropDownS2.Value;
            end


            switch functionTag
                case 'Sine'
                    app.DialogApp = parameterSettingSine(app, ...
                        platformNumber, dimension);
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Button pushed function: VerifyButton
        function verifyMotion(app, event)
            addpath("../functions/")

            % translations
            dataS1X = app.DataMapS1('DataS1X');
            dataS1Y = app.DataMapS1('DataS1Y');
            dataS1Z = app.DataMapS1('DataS1Z');
            
            app.tS1 = [dataS1X{2}, dataS1Y{2}, dataS1Z{2}];
            
            % rotations
            dataS1Yaw = app.DataMapS1('DataS1Yaw');
            dataS1Pitch = app.DataMapS1('DataS1Pitch');
            dataS1Roll = app.DataMapS1('DataS1Roll');

            rotationsS1 = [dataS1Yaw{2}, dataS1Pitch{2}, dataS1Roll{2}];

            % translate rotation and translation for target wrt home target coordinate to rotation and translation for center of top platform wrt base coordinate
            app.transformed_tS1 = zeros(size(app.tS1));
            transformed_rotationsS1 = zeros(size(rotationsS1));
            for i = 1:size(app.tS1, 1)
                % Extract translation and rotation vectors
                ti = app.tS1(i, :)';
                rotationi = rotationsS1(i, :)';
            
                % first translate to center of top platform in home top platform
                % coordinate
                [transformed_ti, transformed_rotationi] = targetToTopPlatform(ti, rotationi, app.topToTarget);
                % then translate to center of top platform in base coordinate
                transformed_ti = transformed_ti + app.baseToTop;
            
                % Store transformed vectors in matrices
                app.transformed_tS1(i, :) = transformed_ti';
                transformed_rotationsS1(i, :) = transformed_rotationi';
            end

            % initailize array that will be saved
            numSamples = numel(dataS1X{1});
            app.pArrayAllTime = zeros(numSamples, 3, 6);
            app.servoAngleAllTime = zeros(numSamples, 1, 6);
            app.housingUnitVectorsAllTime = zeros(numSamples, 3, 3);

            
            successful = true;
            for i=1:numSamples
                pArray = convertToNewFrame(transformed_rotationsS1(i, 1), transformed_rotationsS1(i, 2), ...
                    transformed_rotationsS1(i, 3), transpose(app.transformed_tS1(i, :)), app.pArrayPlatform);
                app.pArrayAllTime(i, :, :) = pArray;

                app.housingUnitVectorsAllTime(i, :, :) = housingCoordinateToBaseCoordinate(app.topToTarget, app.baseToTop, app.tS1(i, :)', rotationsS1(i, :)');

            
                try
                    servoAngleArray = computeServoAngleArray(pArray, app.bArray, app.s, app.a, ...
                        app.betaArray);
                    app.servoAngleAllTime(i, :) = servoAngleArray;
                    
                catch exception
                    successful = false;
                    errorMessage = sprintf('Error in computeServoAngleArray at iteration %d:\n%s', i, exception.message);
                    errordlg(errorMessage, 'Error');
                    break;
                    % error('Terminating the program due to an error.');
                end
                
            end
            if successful
                msgbox('Verification successful!');
            end

            % TODO: add verification for S2


        end

        % Button pushed function: S1Button
        function displayS1Motion(app, event)
            dataS1X = app.DataMapS1('DataS1X');
            numSamples = numel(dataS1X{1});
            for i = 1:numSamples    
                % Update plot
                updateStewartPlatformPlotApp(app.UIAxesAnimationS1, app.transformed_tS1(i, :)', reshape(app.pArrayAllTime(i, :, :), 3, 6), app.bArray, ...
                    app.betaArray, reshape(app.servoAngleAllTime(i, :, :), 1, 6), app.a, app.baseToTop, app.topToTarget, app.tS1(i, :)', reshape(app.housingUnitVectorsAllTime(i, :, :), 3, 3));
                % Set plot properties
                % title(app.UIAxesAnimationS1, 'Stewart Platform Animation');
                % xlabel('X');
                % ylabel('Y');
                % zlabel('Z');
                % grid on;
                
                % Pause to create animation effect
                pause(0.1);
            end
        end

        % Button pushed function: SendButton
        function SendAngleToArduino(app, event)
            addpath("../functions")
            servoAngleAllTimeMotorRange = convertAngleToMotorRange(app.servoAngleAllTime);
            servoAngleAllTimeMotorRange = cat(1, servoAngleAllTimeMotorRange, zeros(1, 1, 6));
            %Connect to Arduino
            arduinoObj = serialport("COM7", 9600); % Update COM3 to your Arduino's port
            for i=1:size(servoAngleAllTimeMotorRange, 1)
                servoAngles = reshape(servoAngleAllTimeMotorRange(i, :, :), 1, 6);
                disp(servoAngles);

                % Give the Arduino time to reset
                pause(0.5);

                % Define an array of numbers
                numbers = servoAngles;
                numbersString = strjoin(string(numbers), ",");
                display(numbersString);
                
                % Send the string to Arduino
                writeline(arduinoObj, numbersString);
            
                % Wait and read confirmation from Arduino
                confirmation = readline(arduinoObj);
            
                % Display the confirmation
                disp(['Confirmation from Arduino: ', confirmation]);
            
            end
            % Clean up
            clear arduinoObj

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.CoordinatesofStewartPlatformsS1andS2Label = uilabel(app.LeftPanel);
            app.CoordinatesofStewartPlatformsS1andS2Label.Text = 'Coordinates of Stewart Platforms (S1 and S2):';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.S1Tab = uitab(app.TabGroup);
            app.S1Tab.Title = 'S1';
            app.P1xEditFieldLabel = uilabel(app.S1Tab);
            app.P1xEditFieldLabel.Text = 'P1-x';
            app.P1xEditField = uieditfield(app.S1Tab, 'numeric');
            app.P1xEditField.ValueDisplayFormat = '%.3f';
            app.P1yEditFieldLabel = uilabel(app.S1Tab);
            app.P1yEditFieldLabel.Text = 'P1-y';
            app.P1yEditField = uieditfield(app.S1Tab, 'numeric');
            app.P1yEditField.ValueDisplayFormat = '%.3f';
            app.P2xEditFieldLabel = uilabel(app.S1Tab);
            app.P2xEditFieldLabel.Text = 'P2-x';
            app.P2xEditField = uieditfield(app.S1Tab, 'numeric');
            app.P2xEditField.ValueDisplayFormat = '%.3f';
            app.P2yEditFieldLabel = uilabel(app.S1Tab);
            app.P2yEditFieldLabel.Text = 'P2-y';
            app.P2yEditField = uieditfield(app.S1Tab, 'numeric');
            app.P2yEditField.ValueDisplayFormat = '%.3f';
            app.SaveValuesButton = uibutton(app.S1Tab, 'push');
            app.SaveValuesButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateS1Position, true);
            app.SaveValuesButton.Text = 'Save Values';
            app.S2Tab = uitab(app.TabGroup);
            app.S2Tab.Title = 'S2';
            app.P1xEditField_4Label = uilabel(app.S2Tab);
            app.P1xEditField_4Label.Text = 'P1-x';
            app.P1xEditField_4 = uieditfield(app.S2Tab, 'numeric');
            app.P1yEditField_4Label = uilabel(app.S2Tab);
            app.P1yEditField_4Label.Text = 'P1-y';
            app.P1yEditField_4 = uieditfield(app.S2Tab, 'numeric');
            app.P2xEditField_4Label = uilabel(app.S2Tab);
            app.P2xEditField_4Label.Text = 'P2-x';
            app.P2xEditField_4 = uieditfield(app.S2Tab, 'numeric');
            app.P2yEditField_4Label = uilabel(app.S2Tab);
            app.P2yEditField_4Label.Text = 'P2-y';
            app.P2yEditField_4 = uieditfield(app.S2Tab, 'numeric');
            app.SaveValuesButton_2 = uibutton(app.S2Tab, 'push');
            app.SaveValuesButton_2.ButtonPushedFcn = createCallbackFcn(app, @EvaluateS2Position, true);
            app.SaveValuesButton_2.Text = 'Save Values';
            app.InitialRelativePositionofTargetsrelativetoS1Label = uilabel(app.LeftPanel);
            app.InitialRelativePositionofTargetsrelativetoS1Label.Text = 'Initial Relative Position of Targets (relative to S1):';
            app.TabGroup_2 = uitabgroup(app.LeftPanel);
            app.S2toS1Tab = uitab(app.TabGroup_2);
            app.S2toS1Tab.Title = 'S2 to S1';
            app.xEditFieldLabel = uilabel(app.S2toS1Tab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.xEditField.ValueDisplayFormat = '%.3f';
            app.yEditFieldLabel = uilabel(app.S2toS1Tab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.yEditField.ValueDisplayFormat = '%.3f';
            app.yawEditField_2Label = uilabel(app.S2toS1Tab);
            app.yawEditField_2Label.Text = 'yaw';
            app.yawEditField_2 = uieditfield(app.S2toS1Tab, 'numeric');
            app.yawEditField_2.ValueDisplayFormat = '%.3f';
            app.pitchEditFieldLabel = uilabel(app.S2toS1Tab);
            app.pitchEditFieldLabel.Text = 'pitch';
            app.pitchEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.pitchEditField.ValueDisplayFormat = '%.3f';
            app.zEditFieldLabel = uilabel(app.S2toS1Tab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.zEditField.ValueDisplayFormat = '%.3f';
            app.rollEditFieldLabel = uilabel(app.S2toS1Tab);
            app.rollEditFieldLabel.Text = 'roll';
            app.rollEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.rollEditField.ValueDisplayFormat = '%.3f';
            app.SaveValuesButton_3 = uibutton(app.S2toS1Tab, 'push');
            app.SaveValuesButton_3.ButtonPushedFcn = createCallbackFcn(app, @EvaluateRelativeDistance, true);
            app.SaveValuesButton_3.Text = 'Save Values';
            app.MotionFileLabel = uilabel(app.LeftPanel);
            app.MotionFileLabel.Text = 'Motion File:';
            app.VerifyMotionLabel = uilabel(app.LeftPanel);
            app.VerifyMotionLabel.Text = 'Verify Motion:';
            app.VerifyButton = uibutton(app.LeftPanel, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @verifyMotion, true);
            app.VerifyButton.Text = 'Verify';
            app.TabGroup4_3 = uitabgroup(app.LeftPanel);
            app.S1Tab_6 = uitab(app.TabGroup4_3);
            app.S1Tab_6.Title = 'S1';
            app.Panel_3 = uipanel(app.S1Tab_6);
            app.UploadButton = uibutton(app.Panel_3, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FileLocationEditFieldLabel = uilabel(app.Panel_3);
            app.FileLocationEditFieldLabel.Text = 'File Location';
            app.FileLocationEditField = uieditfield(app.Panel_3, 'text');
            app.UseCustomMotionLabel = uilabel(app.Panel_3);
            app.UseCustomMotionLabel.Text = 'Use Custom Motion:';
            app.Panel_4 = uipanel(app.S1Tab_6);
            app.FunctionLabel = uilabel(app.Panel_4);
            app.FunctionLabel.Text = 'Function';
            app.ChooseFunctionDropDownS1 = uidropdown(app.Panel_4);
            app.ChooseFunctionDropDownS1.Items = {'Sine'};
            app.ChooseFunctionDropDownS1.Value = 'Sine';
            app.DimensionDropDownLabel = uilabel(app.Panel_4);
            app.DimensionDropDownLabel.Text = 'Dimension';
            app.DimensionDropDownS1 = uidropdown(app.Panel_4);
            app.DimensionDropDownS1.Items = {'x', 'y', 'z', 'yaw', 'pitch', 'roll'};
            app.DimensionDropDownS1.Value = 'x';
            app.SetParametersButtonS1 = uibutton(app.Panel_4, 'push');
            app.SetParametersButtonS1.ButtonPushedFcn = createCallbackFcn(app, @openParameterSettingWindow, true);
            app.SetParametersButtonS1.Tag = 'SetParametersButtonS1';
            app.SetParametersButtonS1.Text = 'Set Parameters';
            app.UsePredefinedMotionLabel = uilabel(app.Panel_4);
            app.UsePredefinedMotionLabel.Text = 'Use Predefined Motion:';
            app.S2Tab_6 = uitab(app.TabGroup4_3);
            app.S2Tab_6.Title = 'S2';
            app.Panel_5 = uipanel(app.S2Tab_6);
            app.UploadButton_2 = uibutton(app.Panel_5, 'push');
            app.UploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_2Pushed, true);
            app.UploadButton_2.Text = 'Upload';
            app.FileLocationEditField_2Label = uilabel(app.Panel_5);
            app.FileLocationEditField_2Label.Text = 'File Location';
            app.FileLocationEditField_2 = uieditfield(app.Panel_5, 'text');
            app.UseCustomMotionLabel_2 = uilabel(app.Panel_5);
            app.UseCustomMotionLabel_2.Text = 'Use Custom Motion:';
            app.Panel_6 = uipanel(app.S2Tab_6);
            app.FunctionLabel_2 = uilabel(app.Panel_6);
            app.FunctionLabel_2.Text = 'Function';
            app.ChooseFunctionDropDownS2 = uidropdown(app.Panel_6);
            app.ChooseFunctionDropDownS2.Items = {'Sine'};
            app.ChooseFunctionDropDownS2.Value = 'Sine';
            app.DimensionDropDown_2Label = uilabel(app.Panel_6);
            app.DimensionDropDown_2Label.Text = 'Dimension';
            app.DimensionDropDownS2 = uidropdown(app.Panel_6);
            app.DimensionDropDownS2.Items = {'x', 'y', 'z', 'yaw', 'pitch', 'roll'};
            app.DimensionDropDownS2.Value = 'x';
            app.SetParametersButtonS2 = uibutton(app.Panel_6, 'push');
            app.SetParametersButtonS2.ButtonPushedFcn = createCallbackFcn(app, @openParameterSettingWindow, true);
            app.SetParametersButtonS2.Tag = 'SetParametersButtonS2';
            app.SetParametersButtonS2.Text = 'Set Parameters';
            app.UsePredefinedMotionLabel_2 = uilabel(app.Panel_6);
            app.UsePredefinedMotionLabel_2.Text = 'Use Predefined Motion:';
            app.StartStewartPlatformMotionPreviewLabel = uilabel(app.LeftPanel);
            app.StartStewartPlatformMotionPreviewLabel.Text = 'Start Stewart Platform Motion Preview:';
            app.S1Button = uibutton(app.LeftPanel, 'push');
            app.S1Button.ButtonPushedFcn = createCallbackFcn(app, @displayS1Motion, true);
            app.S1Button.Text = 'S1';
            app.S2Button = uibutton(app.LeftPanel, 'push');
            app.S2Button.Text = 'S2';
            app.SendDatatoArduinoLabel = uilabel(app.LeftPanel);
            app.SendDatatoArduinoLabel.Text = 'Send Data to Arduino:';
            app.SendButton = uibutton(app.LeftPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendAngleToArduino, true);
            app.SendButton.Text = 'Send';
            app.RightPanel = uipanel(app.UIFigure);
            app.MotionWavePreviewLabel = uilabel(app.RightPanel);
            app.MotionWavePreviewLabel.Text = 'Motion Wave Preview:';
            app.TabGroup4 = uitabgroup(app.RightPanel);
            app.S1Tab_4 = uitab(app.TabGroup4);
            app.S1Tab_4.Title = 'S1';
            app.UIAxesWaveS1 = uiaxes(app.S1Tab_4);
            title(app.UIAxesWaveS1, 'Waveform')
            xlabel(app.UIAxesWaveS1, 'X')
            ylabel(app.UIAxesWaveS1, 'Y')
            zlabel(app.UIAxesWaveS1, 'Z')
            app.S2Tab_4 = uitab(app.TabGroup4);
            app.S2Tab_4.Title = 'S2';
            app.UIAxesWaveS2 = uiaxes(app.S2Tab_4);
            title(app.UIAxesWaveS2, 'Waveform')
            xlabel(app.UIAxesWaveS2, 'X')
            ylabel(app.UIAxesWaveS2, 'Y')
            zlabel(app.UIAxesWaveS2, 'Z')
            app.StewartPlatformMotionPreviewLabel = uilabel(app.RightPanel);
            app.StewartPlatformMotionPreviewLabel.Text = 'Stewart Platform Motion Preview:';
            app.TabGroup4_2 = uitabgroup(app.RightPanel);
            app.S1Tab_5 = uitab(app.TabGroup4_2);
            app.S1Tab_5.Title = 'S1';
            app.UIAxesAnimationS1 = uiaxes(app.S1Tab_5);
            title(app.UIAxesAnimationS1, 'Motion Preview')
            xlabel(app.UIAxesAnimationS1, 'X')
            ylabel(app.UIAxesAnimationS1, 'Y')
            zlabel(app.UIAxesAnimationS1, 'Z')
            app.S2Tab_5 = uitab(app.TabGroup4_2);
            app.S2Tab_5.Title = 'S2';
            app.UIAxesAnimationS2 = uiaxes(app.S2Tab_5);
            title(app.UIAxesAnimationS2, 'Motion Preview')
            xlabel(app.UIAxesAnimationS2, 'X')
            ylabel(app.UIAxesAnimationS2, 'Y')
            zlabel(app.UIAxesAnimationS2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = funcUploadButtonPushedChecksData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end