classdef parameterSettingSine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        samplingrateEditField         matlab.ui.control.NumericEditField
        samplingrateEditFieldLabel    matlab.ui.control.Label
        durationEditField             matlab.ui.control.NumericEditField
        durationEditFieldLabel        matlab.ui.control.Label
        amplitudeshiftEditField       matlab.ui.control.NumericEditField
        amplitudeshiftEditFieldLabel  matlab.ui.control.Label
        timeshiftEditField            matlab.ui.control.NumericEditField
        timeshiftEditFieldLabel       matlab.ui.control.Label
        frequencyEditField            matlab.ui.control.NumericEditField
        frequencyEditFieldLabel       matlab.ui.control.Label
        amplitudeEditField            matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel       matlab.ui.control.Label
        confirmButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % main app
        PlatformNumber % an int indicates we're producing the motion for which platform
        Dimension % one of "x", "y", "z", "yaw", "pitch", "roll"
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, platformNumber, dimension)
            app.CallingApp = callingApp;
            app.PlatformNumber = platformNumber;
            app.Dimension = dimension;
        end

        % Button pushed function: confirmButton
        function sendDataToMainApp(app, event)
            addpath("../functions/")
            amplitude = app.amplitudeEditField.Value;
            frequency = app.frequencyEditField.Value;
            timeShift = app.timeshiftEditField.Value;
            amplitudeShift = app.amplitudeshiftEditField.Value;
            duration = app.durationEditField.Value;
            samplingRate = app.samplingrateEditField.Value;

            numSamples = round(duration * samplingRate);
            dataX = transpose(linspace(0, duration, numSamples));
            dataY = generateSineData(amplitude, frequency, timeShift, ...
                amplitudeShift, duration, samplingRate);

            % app.CallingApp.displayVariableValue("i'm here");

            app.CallingApp.updateMotionData(app.PlatformNumber, app.Dimension, dataX, dataY);
            % app.CallingApp.updateMotionData(app.CallingApp, app.PlatformNumber, app.Dimension, dataX, dataY);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.switchSetParameterButtons("on");
            
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.confirmButton = uibutton(app.UIFigure, 'push');
            app.confirmButton.ButtonPushedFcn = createCallbackFcn(app, @sendDataToMainApp, true);
            app.confirmButton.Text = 'confirm';
            app.amplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.frequencyEditFieldLabel = uilabel(app.UIFigure);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.frequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.timeshiftEditFieldLabel = uilabel(app.UIFigure);
            app.timeshiftEditFieldLabel.Text = 'time shift';
            app.timeshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.amplitudeshiftEditFieldLabel = uilabel(app.UIFigure);
            app.amplitudeshiftEditFieldLabel.Text = 'amplitude shift';
            app.amplitudeshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.durationEditFieldLabel = uilabel(app.UIFigure);
            app.durationEditFieldLabel.Text = 'duration';
            app.durationEditField = uieditfield(app.UIFigure, 'numeric');
            app.samplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.samplingrateEditFieldLabel.Text = 'sampling rate';
            app.samplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parameterSettingSine(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end