classdef sineParameterSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        samplingrateEditField         matlab.ui.control.NumericEditField
        samplingrateEditFieldLabel    matlab.ui.control.Label
        durationEditField             matlab.ui.control.NumericEditField
        durationEditFieldLabel        matlab.ui.control.Label
        amplitudeshiftEditField       matlab.ui.control.NumericEditField
        amplitudeshiftEditFieldLabel  matlab.ui.control.Label
        timeshiftEditField            matlab.ui.control.NumericEditField
        timeshiftEditFieldLabel       matlab.ui.control.Label
        frequencyEditField            matlab.ui.control.NumericEditField
        frequencyEditFieldLabel       matlab.ui.control.Label
        amplitudeEditField            matlab.ui.control.NumericEditField
        amplitudeEditFieldLabel       matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
 
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: amplitudeEditField, 
        % ...and 5 other components
        function parameterChanged(app, event)
            addpath("../functions/")

            amplitude = app.amplitudeEditField.Value;
            frequency = app.frequencyEditField.Value;
            timeShift = app.timeshiftEditField.Value;
            amplitudeShift = app.amplitudeshiftEditField.Value;
            duration = app.durationEditField.Value;
            samplingRate = app.samplingrateEditField.Value;
           

            numSamples = round(duration * samplingRate);
            t = linspace(0, duration, numSamples);
            sineData = generateSineData(amplitude, frequency, timeShift, ...
                amplitudeShift, duration, samplingRate);
          
            plot(app.UIAxes, t, sineData)
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {350, 350};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.amplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.amplitudeEditFieldLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.amplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.frequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.frequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.frequencyEditField.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.timeshiftEditFieldLabel = uilabel(app.LeftPanel);
            app.timeshiftEditFieldLabel.Text = 'time shift';
            app.timeshiftEditField = uieditfield(app.LeftPanel, 'numeric');
            app.timeshiftEditField.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.amplitudeshiftEditFieldLabel = uilabel(app.LeftPanel);
            app.amplitudeshiftEditFieldLabel.Text = 'amplitude shift';
            app.amplitudeshiftEditField = uieditfield(app.LeftPanel, 'numeric');
            app.amplitudeshiftEditField.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.durationEditFieldLabel = uilabel(app.LeftPanel);
            app.durationEditFieldLabel.Text = 'duration';
            app.durationEditField = uieditfield(app.LeftPanel, 'numeric');
            app.durationEditField.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.samplingrateEditFieldLabel = uilabel(app.LeftPanel);
            app.samplingrateEditFieldLabel.Text = 'sampling rate';
            app.samplingrateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.samplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @parameterChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Sine Wave Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sineParameterSetting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end