classdef singleStewartPlatformControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RightPanel                  matlab.ui.container.Panel
        StartButton                 matlab.ui.control.Button
        ConverttoStewartPlatformMotionLabel  matlab.ui.control.Label
        ShowButton                  matlab.ui.control.Button
        StewartPlatformMotionPreviewLabel_2  matlab.ui.control.Label
        UIAxesAnimationS1           matlab.ui.control.UIAxes
        LeftPanel                   matlab.ui.container.Panel
        MotionWavePreviewLabel      matlab.ui.control.Label
        Panel_4                     matlab.ui.container.Panel
        UsePredefinedMotionLabel    matlab.ui.control.Label
        SetParametersButtonS1       matlab.ui.control.Button
        DimensionDropDownS1         matlab.ui.control.DropDown
        DimensionDropDownLabel      matlab.ui.control.Label
        ChooseFunctionDropDownS1    matlab.ui.control.DropDown
        FunctionLabel               matlab.ui.control.Label
        Panel_3                     matlab.ui.container.Panel
        UseCustomMotionLabel        matlab.ui.control.Label
        FileLocationEditField       matlab.ui.control.EditField
        FileLocationEditFieldLabel  matlab.ui.control.Label
        UploadButton                matlab.ui.control.Button
        MotionFileLabel             matlab.ui.control.Label
        UIAxesWaveS1                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DialogApp 
        DataMapS1 containers.Map
        DataMapS2 containers.Map
        % configurations for Stewart Platform
        bArray
        pArrayPlatform
        s
        a
        betaArray
        % Stewart Platform motion related calculated data
        pArrayAllTime
        servoAngleAllTime
        tS1
    end
    
    methods (Access = private)
        
        function initializeDataMap(app)
            app.DataMapS1 = containers.Map;
            app.DataMapS2 = containers.Map;
            keysListS1 = {'DataS1X', 'DataS1Y', 'DataS1Z', 'DataS1Yaw', 'DataS1Pitch', 'DataS1Roll'};
            keysListS2 = {'DataS2X', 'DataS2Y', 'DataS2Z', 'DataS2Yaw', 'DataS2Pitch', 'DataS2Roll'};
            for i = 1:length(keysListS1)
                app.DataMapS1(keysListS1{i}) = [];
            end
            for i = 1:length(keysListS2)
                app.DataMapS2(keysListS2{i}) = [];
            end
        end
        
        function initializeStewartPlatformConfigurations(app)
            app.bArray = [90.53, 49.00, -46.66, -89.04, -41.28, 42.62; ...
                 0, 74.28, 74.28, 0, -81.72, -82.72; ...
                 0, 0, 0, 0, 0, 0]; % 3 * 6
            app.pArrayPlatform = 0.6 * app.bArray; % 3 * 6
            app.s = 100;
            app.a = 25;
            app.betaArray = deg2rad([120 + 180, 120 + 180, 240 + 180, ...
                240 + 180, 360 + 180, 360 + 180]); % 1 * 6, add 180 since motors face inwards
            
        end
        
     
    end
    
    methods (Access = public)

        function switchSetParameterButtons(app, swtich)
            app.SetParametersButtonS1.Enable = swtich;
            % app.SetParametersButtonS2.Enable = swtich;
        end

        
        function updateMotionData(app, platformNumber, ...
                dimension, motionX, motionY)
            if platformNumber == 1
                dataMap = app.DataMapS1;
                plotAxes = app.UIAxesWaveS1;    
            else
                dataMap = app.DataMapS2;
                plotAxes = app.UIAxesWaveS2;   
            end

            key = char(strcat('DataS', num2str(platformNumber), ...
                        string(upper(dimension(1))), string(dimension(2:end)))); 
            dataMap(key) = {motionX, motionY};
            keys = dataMap.keys;

            cla(plotAxes);
            hold(plotAxes, 'on');
            for i = 1:length(keys)
                curveData = dataMap(keys{i});
                if ~isempty(curveData)
                    plot(plotAxes, curveData{1}, curveData{2}, 'DisplayName', keys{i});
                end
            end
            hold(plotAxes, 'off');
            legend(plotAxes, 'show');
  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeDataMap(app);
            initializeStewartPlatformConfigurations(app);

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
           % Open file dialog
            [fileName, filePath] = uigetfile({'*.txt;*.csv', 'Text files (*.txt, *.csv)'; '*.*', 'All files (*.*)'}, 'Select Data File');
            
            % Check if the user selected a file
            if fileName ~= 0
                try
                    % Read data from the file
                    data = dlmread(fullfile(filePath, fileName));
            
                    % Extract the first row as NumberOfDataPoints
                    NumberOfDataPoints = data(1);
            
                    % Extract the second row as SamplingRate
                    SamplingRate = data(2);
            
                    % Extract columns after the second row as separate variables
                    XData = data(3:end, 1);
                    YData = data(3:end, 2);
                    ZData = data(3:end, 3);
                    YawData = data(3:end, 4);
                    PitchData = data(3:end, 5);
                    RollData = data(3:end, 6);

                    % Check for empty columns
                    isEmptyXData = isempty(XData);
                    isEmptyYData = isempty(YData);
                    isEmptyZData = isempty(ZData);
                    isEmptyYawData = isempty(YawData);
                    isEmptyPitchData = isempty(PitchData);
                    isEmptyRollData = isempty(RollData);
            
                    if isEmptyXData
                        warndlg('Warning: XData is empty.');
                    end
            
                    if isEmptyYData
                        warndlg('Warning: YData is empty.');
                    end
            
                    if isEmptyZData
                        warndlg('Warning: ZData is empty.');
                    end
            
                    if isEmptyYawData
                        warndlg('Warning: YawData is empty.');
                    end
            
                    if isEmptyPitchData
                        warndlg('Warning: PitchData is empty.');
                    end
            
                    if isEmptyRollData
                        warndlg('Warning: RollData is empty.');
                    end

                  
                    % Check for valid values
                    isValidXData = all(~isnan(XData));
                    isValidYData = all(~isnan(YData));
                    isValidZData = all(~isnan(ZData));
                    isValidYawData = all(~isnan(YawData));
                    isValidPitchData = all(~isnan(PitchData));
                    isValidRollData = all(~isnan(RollData));
            
                    % Check the number of data points
                    isValidNumberOfDataPoints = NumberOfDataPoints == length(XData);
                                
                    % Display validity and data types
                    if isValidXData && isValidYData && isValidZData && isValidYawData && isValidPitchData && isValidRollData && isValidNumberOfDataPoints
                        disp('All values are valid.');
                    else
                        disp('Warning: Some values are not valid or inconsistent.');
                    end
            
                    fprintf('Data type of XData: %s\n', class(XData));
                    fprintf('Data type of YData: %s\n', class(YData));
                    fprintf('Data type of ZData: %s\n', class(ZData));
                    fprintf('Data type of YawData: %s\n', class(YawData));
                    fprintf('Data type of PitchData: %s\n', class(PitchData));
                    fprintf('Data type of RollData: %s\n', class(RollData));
            
                    % Display warnings for inconsistency or empty columns
                    if ~isValidNumberOfDataPoints
                        warndlg('Warning: Inconsistent number of data points.');
                    end
                                
            
                catch
                    % Display a warning pop-up message if an error occurs
                    warndlg('Error reading the motion file data. Missing a column of data or data contains non-numerical value.');
                end
 
                % TODO: data should save to private property

            end

        end

        % Button pushed function: SetParametersButtonS1
        function openParameterSettingWindow(app, event)
            % disables buttons to prevent open multiple dialog window
            switchSetParameterButtons(app, "off");

            sourceButton = event.Source;
            buttonTag = sourceButton.Tag;
            % displayVariableValue(app, buttonTag);
      
            switch buttonTag
                case 'SetParametersButtonS1'
                    platformNumber = 1;
                    functionTag = app.ChooseFunctionDropDownS1.Value;
                    dimension = app.DimensionDropDownS1.Value;
                case 'SetParametersButtonS2'
                    platformNumber = 2;
                    functionTag = app.ChooseFunctionDropDownS2.Value;
                    dimension = app.DimensionDropDownS2.Value;
            end


            switch functionTag
                case 'Sine'
                    app.DialogApp = parameterSettingSine(app, ...
                        platformNumber, dimension);
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Button pushed function: StartButton
        function verifyMotion(app, event)
            addpath("../functions/")

            % translations
            dataS1X = app.DataMapS1('DataS1X');
            dataS1Y = app.DataMapS1('DataS1Y');
            dataS1Z = app.DataMapS1('DataS1Z');
            
            app.tS1 = [dataS1X{2}, dataS1Y{2}, dataS1Z{2}];
            
            % rotations
            dataS1Yaw = app.DataMapS1('DataS1Yaw');
            dataS1Pitch = app.DataMapS1('DataS1Pitch');
            dataS1Roll = app.DataMapS1('DataS1Roll');

            rotationsS1 = [dataS1Yaw{2}, dataS1Pitch{2}, dataS1Roll{2}];

            % initailize array that will be saved
            numSamples = numel(dataS1X{1});
            app.pArrayAllTime = zeros(numSamples, 3, 6);
            app.servoAngleAllTime = zeros(numSamples, 1, 6);
            
            successful = true;
            for i=1:numSamples
                pArray = convertToNewFrame(rotationsS1(i, 1), rotationsS1(i, 2), ...
                    rotationsS1(i, 3), transpose(app.tS1(i, :)), app.pArrayPlatform);
                app.pArrayAllTime(i, :, :) = pArray;
            
                try
                    servoAngleArray = computeServoAngleArray(pArray, app.bArray, app.s, app.a, ...
                        app.betaArray);
                    app.servoAngleAllTime(i, :) = servoAngleArray;
                    
                catch exception
                    successful = false;
                    errorMessage = sprintf('Error in computeServoAngleArray at iteration %d:\n%s', i, exception.message);
                    errordlg(errorMessage, 'Error', 'modal');
                    break;
                    % error('Terminating the program due to an error.');
                end
                
            end
            if successful
                msgbox('Verification successful!', 'Success', 'modal');
            end

            % TODO: add verification for S2


        end

        % Button pushed function: ShowButton
        function displayS1Motion(app, event)
            dataS1X = app.DataMapS1('DataS1X');
            numSamples = numel(dataS1X{1});
            for i = 1:numSamples    
                % Update plot
                updateStewartPlatformPlotApp(app.UIAxesAnimationS1,app.tS1(i, :), reshape(app.pArrayAllTime(i, :, :), 3, 6), app.bArray, ...
                    app.betaArray, reshape(app.servoAngleAllTime(i, :, :), 1, 6), app.a);
                % Set plot properties
                % title(app.UIAxesAnimationS1, 'Stewart Platform Animation');
                % xlabel('X');
                % ylabel('Y');
                % zlabel('Z');
                % grid on;
                
                % Pause to create animation effect
                pause(0.1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.UIAxesWaveS1 = uiaxes(app.LeftPanel);
            title(app.UIAxesWaveS1, 'Waveform')
            xlabel(app.UIAxesWaveS1, 'X')
            ylabel(app.UIAxesWaveS1, 'Y')
            zlabel(app.UIAxesWaveS1, 'Z')
            app.MotionFileLabel = uilabel(app.LeftPanel);
            app.MotionFileLabel.Text = 'Motion File:';
            app.Panel_3 = uipanel(app.LeftPanel);
            app.UploadButton = uibutton(app.Panel_3, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FileLocationEditFieldLabel = uilabel(app.Panel_3);
            app.FileLocationEditFieldLabel.Text = 'File Location';
            app.FileLocationEditField = uieditfield(app.Panel_3, 'text');
            app.UseCustomMotionLabel = uilabel(app.Panel_3);
            app.UseCustomMotionLabel.Text = 'Use Custom Motion:';
            app.Panel_4 = uipanel(app.LeftPanel);
            app.FunctionLabel = uilabel(app.Panel_4);
            app.FunctionLabel.Text = 'Function';
            app.ChooseFunctionDropDownS1 = uidropdown(app.Panel_4);
            app.ChooseFunctionDropDownS1.Items = {'Sine'};
            app.ChooseFunctionDropDownS1.Value = 'Sine';
            app.DimensionDropDownLabel = uilabel(app.Panel_4);
            app.DimensionDropDownLabel.Text = 'Dimension';
            app.DimensionDropDownS1 = uidropdown(app.Panel_4);
            app.DimensionDropDownS1.Items = {'x', 'y', 'z', 'yaw', 'pitch', 'roll'};
            app.DimensionDropDownS1.Value = 'x';
            app.SetParametersButtonS1 = uibutton(app.Panel_4, 'push');
            app.SetParametersButtonS1.ButtonPushedFcn = createCallbackFcn(app, @openParameterSettingWindow, true);
            app.SetParametersButtonS1.Tag = 'SetParametersButtonS1';
            app.SetParametersButtonS1.Text = 'Set Parameters';
            app.UsePredefinedMotionLabel = uilabel(app.Panel_4);
            app.UsePredefinedMotionLabel.Text = 'Use Predefined Motion:';
            app.MotionWavePreviewLabel = uilabel(app.LeftPanel);
            app.MotionWavePreviewLabel.Text = 'Motion Wave Preview:';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxesAnimationS1 = uiaxes(app.RightPanel);
            title(app.UIAxesAnimationS1, 'Motion Preview')
            xlabel(app.UIAxesAnimationS1, 'X')
            ylabel(app.UIAxesAnimationS1, 'Y')
            zlabel(app.UIAxesAnimationS1, 'Z')
            app.StewartPlatformMotionPreviewLabel_2 = uilabel(app.RightPanel);
            app.StewartPlatformMotionPreviewLabel_2.Text = 'Stewart Platform Motion Preview:';
            app.ShowButton = uibutton(app.RightPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @displayS1Motion, true);
            app.ShowButton.Text = 'Show';
            app.ConverttoStewartPlatformMotionLabel = uilabel(app.RightPanel);
            app.ConverttoStewartPlatformMotionLabel.Text = 'Convert to Stewart Platform Motion:';
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @verifyMotion, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = singleStewartPlatformControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end